classdef EWSignalAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ClassificationResultLabel  matlab.ui.control.Label
        BandwidthTextArea          matlab.ui.control.TextArea
        TextArea3Label             matlab.ui.control.Label
        PeakFreqTextArea           matlab.ui.control.TextArea
        TextArea2Label             matlab.ui.control.Label
        EnergyTextArea             matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
        ClassifySignalButton       matlab.ui.control.Button
        GenerateSignalButton       matlab.ui.control.Button
        FFTAxes                    matlab.ui.control.UIAxes
        FilteredSignalAxes         matlab.ui.control.UIAxes
        TimeDomainAxes             matlab.ui.control.UIAxes
    end

    % Properties for internal use
    properties (Access = private)
        fs % Sampling frequency
        signal % Generated signal
        noisySignal % Noisy Signal
        filteredSignal % Filtered Signal
        SVMModel % Loaded SVM Model
    end
    
    methods (Access = private)
        
        function startupFcn(app)
            load(fullfile('Big_Iron','SVMModel.m'),'SVMModel');
            app.SVMModel = SVMModel;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
            % Signal parameters
            app.fs = 1000; % Sampling frequency
            t = 0:1/app.fs:1; % Time vector (1 second)
            
            % Generate random signal frequency
            signalFreq = randi([80, 120]); % Random frequency (80-120 Hz)
            app.signal = sin(2 * pi * signalFreq * t);
            
            % Add noise
            noisePower = rand * 0.5;
            app.noisySignal = app.signal + sqrt(noisePower) * randn(size(t));
            
            % Filter the signal
            filterOrder = 4;
            cutoffFreq = 150; % Hz
            [b, a] = butter(filterOrder, cutoffFreq/(app.fs/2), 'low');
            app.filteredSignal = filtfilt(b, a, app.noisySignal);
            
            % Plot signals
            plot(app.TimeDomainAxes, t, app.noisySignal);
            title(app.TimeDomainAxes, 'Noisy Signal (Time Domain)');
            xlabel(app.TimeDomainAxes, 'Time (s)');
            ylabel(app.TimeDomainAxes, 'Amplitude');
            
            plot(app.FilteredSignalAxes, t, app.filteredSignal);
            title(app.FilteredSignalAxes, 'Filtered Signal (Time Domain)');
            xlabel(app.FilteredSignalAxes, 'Time (s)');
            ylabel(app.FilteredSignalAxes, 'Amplitude');
        end

        % Button pushed function: ClassifySignalButton
        function ClassifySignalButtonPushed(app, event)
            % Perform FFT on the filtered signal
            fftSignal = fft(app.filteredSignal);
            frequencies = (0:length(fftSignal)-1) * app.fs / length(fftSignal);
            
            % Plot FFT
            plot(app.FFTAxes, frequencies, abs(fftSignal));
            title(app.FFTAxes, 'Frequency Domain (FFT)');
            xlabel(app.FFTAxes, 'Frequency (Hz)');
            ylabel(app.FFTAxes, 'Magnitude');
            
            % Extract features
            signalEnergy = sum(app.filteredSignal.^2);
            [~, peakIndex] = max(abs(fftSignal));
            peakFrequency = frequencies(peakIndex);
            powerSpectrum = abs(fftSignal).^2;
            totalPower = sum(powerSpectrum);
            cumulativePower = cumsum(powerSpectrum);
            lowerIndex = find(cumulativePower >= 0.05 * totalPower, 1, 'first');
            upperIndex = find(cumulativePower >= 0.95 * totalPower, 1, 'first');
            bandwidth = frequencies(upperIndex) - frequencies(lowerIndex);
            
            % Display features
            app.EnergyTextArea.Value = sprintf('Energy: %.2f', signalEnergy);
            app.PeakFreqTextArea.Value = sprintf('Peak Frequency: %.2f Hz', peakFrequency);
            app.BandwidthTextArea.Value = sprintf('Bandwidth: %.2f Hz', bandwidth);
            
            % Classify signal
            testFeatures = [signalEnergy, peakFrequency, bandwidth];
            predictedLabel = predict(app.SVMModel, testFeatures);
            if predictedLabel == 1
                app.ClassificationResultLabel.Text = 'Threat Detected!';
                app.ClassificationResultLabel.FontColor = [1, 0, 0]; % Red
            else
                app.ClassificationResultLabel.Text = 'Normal Signal';
                app.ClassificationResultLabel.FontColor = [0, 1, 0]; % Green
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeDomainAxes = uiaxes(app.UIFigure);
            title(app.TimeDomainAxes, 'Title')
            xlabel(app.TimeDomainAxes, 'X')
            ylabel(app.TimeDomainAxes, 'Y')
            zlabel(app.TimeDomainAxes, 'Z')
            app.FilteredSignalAxes = uiaxes(app.UIFigure);
            title(app.FilteredSignalAxes, 'Title')
            xlabel(app.FilteredSignalAxes, 'X')
            ylabel(app.FilteredSignalAxes, 'Y')
            zlabel(app.FilteredSignalAxes, 'Z')
            app.FFTAxes = uiaxes(app.UIFigure);
            title(app.FFTAxes, 'Title')
            xlabel(app.FFTAxes, 'X')
            ylabel(app.FFTAxes, 'Y')
            zlabel(app.FFTAxes, 'Z')
            app.GenerateSignalButton = uibutton(app.UIFigure, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate Signal';
            app.ClassifySignalButton = uibutton(app.UIFigure, 'push');
            app.ClassifySignalButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifySignalButtonPushed, true);
            app.ClassifySignalButton.Text = 'Classify Signal';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.EnergyTextArea = uitextarea(app.UIFigure);
            app.TextArea2Label = uilabel(app.UIFigure);
            app.TextArea2Label.Text = 'Text Area2';
            app.PeakFreqTextArea = uitextarea(app.UIFigure);
            app.TextArea3Label = uilabel(app.UIFigure);
            app.TextArea3Label.Text = 'Text Area3';
            app.BandwidthTextArea = uitextarea(app.UIFigure);
            app.ClassificationResultLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EWSignalAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end